classdef MatlabResultWork < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Menu       matlab.ui.container.Menu
        New        matlab.ui.container.Menu
        Open       matlab.ui.container.Menu
        Save       matlab.ui.container.Menu
        Close      matlab.ui.container.Menu
        Menu_8     matlab.ui.container.Menu
        Menu_3     matlab.ui.container.Menu
        MenuGraph  matlab.ui.container.Menu
        MenuMin    matlab.ui.container.Menu
        MenuMax    matlab.ui.container.Menu
        OnlyGraph  matlab.ui.control.StateButton
        Min        matlab.ui.control.StateButton
        Max        matlab.ui.control.StateButton
        Graph      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        filename = 0;
        ToF = 0;
    end
    
    methods (Access = private)
        
        % Plot graph
        function PlotGraph(app)
            cla(app.Graph);
            table = readtable(app.filename);
            table.Properties.VariableNames{1} = 'Date';
            table.Properties.VariableNames{2} = 'Data';
            
            dates = table.Date;
            dates = fillmissing(dates,'previous');
            datas = table.Data;
            datas = fillmissing(datas,'previous');
            datasSize = size(datas);
            datasSize = datasSize(1);
            datasArray= zeros(datasSize,1);
            if iscell(datas)
                for i = 1:datasSize
                    datasArray(i) = sscanf(datas{i}, '%f*');
                end
            else
                datasArray = datas;
            end
            if dates(1)>dates(2)
                dates = flip(dates,1);
                datasArray = flip(datasArray,1);
            end
            hold(app.Graph,"on")
            plot(app.Graph,dates, datasArray);
            if app.ToF~=0
                thirdColumn = SearchMinMax(app,dates,datasArray);
                answerTable = ToTable(app, dates, datasArray, thirdColumn);
                answerTable.Properties.VariableNames{1} = 'Date';
                answerTable.Properties.VariableNames{2} = 'Data';
                if app.ToF == 1
                    answerTable.Properties.VariableNames{3} = 'Min';
                else
                    answerTable.Properties.VariableNames{3} = 'Max';
                end
                writetable(answerTable,app.filename);
                plot(app.Graph,dates, thirdColumn);
            end
            hold(app.Graph,"off")
        end
        
        %Calculator of min or max
        function thirdColumn = SearchMinMax(app, dates,datas)
            counter = 1;
            datasSize = size(datas);            
            datasSize = datasSize(1);
            thirdColumn = zeros(datasSize,1);
            if app.ToF==1
                thirdColumn(1) = counter;
                for i = 2:datasSize(1)
                    if dates(i)~=dates(i-1)
                        if datas(i)<datas(i-1)
                            counter = counter+1;
                            thirdColumn(i) = counter;
                        elseif datas(i)>=datas(i-1)
                            counter = 0;
                            thirdColumn(i) = counter;
                        end
                    end
                end
            else
                thirdColumn(1) = counter;
                for i = 2:datasSize(1)
                    if dates(i)~=dates(i-1)
                        if datas(i)>datas(i-1)
                            counter = counter+1;
                            thirdColumn(i) = counter;
                        elseif datas(i)<=datas(i-1)
                            counter = 0;
                            thirdColumn(i) = counter;
                        end
                    end
                end
            end
            return;
        end
        function table = ToTable(app, dates,datas, thirdColumn)
            datasSize = size(datas);
            datasSize = datasSize(1);
            for i = 1:datasSize
                  table{i,1} = dates(i);
                  table{i,2} = datas(i);
                  table{i,3} = thirdColumn(i);
            end
            table = cell2table(table);
            return;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Open
        function OpenSelected(app, event)
            [file,path] = uigetfile({'*.csv'; '*.xls'; '*.xlsb'; '*.xlsm'; '*.xlsx*'; '*.dat'; '*.txt'; '*.xml'});
            app.filename = [path file];
            if app.filename~=0
                app.ToF = 0;
                loadobj(Ok);
                app.PlotGraph;
            end
        end

        % Menu selected function: Close
        function CloseSelected(app, event)
            delete(app.UIFigure)
        end

        % Menu selected function: New
        function NewSelected(app, event)
            cla(app.Graph);
        end

        % Menu selected function: MenuGraph
        function MenuGraphSelected(app, event)
        if app.filename~=0
            app.ToF = 0;
            app.PlotGraph;
        end
        end

        % Value changed function: OnlyGraph
        function OnlyGraphValueChanged(app, event)
        if app.filename~=0
            app.ToF = 0;
            app.PlotGraph;
        end
        end

        % Value changed function: Min
        function MinValueChanged(app, event)
            if app.filename~=0
                app.ToF = 1;
                app.PlotGraph;
            end
        end

        % Value changed function: Max
        function MaxValueChanged(app, event)
            if app.filename~=0
                app.ToF = 2;                
                app.PlotGraph;
            end  
        end

        % Menu selected function: MenuMin
        function MenuMinSelected(app, event)
            if app.filename~=0
                app.ToF = 1;
                app.PlotGraph;
            end   
        end

        % Menu selected function: MenuMax
        function MenuMaxSelected(app, event)
            if app.filename~=0
                app.ToF = 2;                
                app.PlotGraph;
            end  
        end

        % Menu selected function: Save
        function SaveMenuSelected(app, event)
            exportgraphics(app.Graph,'graph.pdf');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Файл';
            app.New = uimenu(app.Menu);
            app.New.MenuSelectedFcn = createCallbackFcn(app, @NewSelected, true);
            app.New.Text = 'Новый';
            app.Open = uimenu(app.Menu);
            app.Open.MenuSelectedFcn = createCallbackFcn(app, @OpenSelected, true);
            app.Open.Text = 'Открыть...';
            app.Save = uimenu(app.Menu);
            app.Save.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.Save.Text = 'Сохранить график';
            app.Close = uimenu(app.Menu);
            app.Close.MenuSelectedFcn = createCallbackFcn(app, @CloseSelected, true);
            app.Close.Text = 'Выход';
            app.Menu_8 = uimenu(app.UIFigure);
            app.Menu_8.Text = 'Запуск';
            app.Menu_3 = uimenu(app.Menu_8);
            app.Menu_3.Text = 'Построить график';
            app.MenuGraph = uimenu(app.Menu_3);
            app.MenuGraph.MenuSelectedFcn = createCallbackFcn(app, @MenuGraphSelected, true);
            app.MenuGraph.Text = 'График';
            app.MenuMin = uimenu(app.Menu_3);
            app.MenuMin.MenuSelectedFcn = createCallbackFcn(app, @MenuMinSelected, true);
            app.MenuMin.Text = 'Минимум';
            app.MenuMax = uimenu(app.Menu_3);
            app.MenuMax.MenuSelectedFcn = createCallbackFcn(app, @MenuMaxSelected, true);
            app.MenuMax.Text = 'Максимум';
            app.OnlyGraph = uibutton(app.UIFigure, 'state');
            app.OnlyGraph.ValueChangedFcn = createCallbackFcn(app, @OnlyGraphValueChanged, true);
            app.OnlyGraph.Text = 'Только график';
            app.Min = uibutton(app.UIFigure, 'state');
            app.Min.ValueChangedFcn = createCallbackFcn(app, @MinValueChanged, true);
            app.Min.Text = 'Минимум';
            app.Max = uibutton(app.UIFigure, 'state');
            app.Max.ValueChangedFcn = createCallbackFcn(app, @MaxValueChanged, true);
            app.Max.Text = 'Максимум';
            app.Graph = uiaxes(app.UIFigure);
            title(app.Graph, 'Graph')
            xlabel(app.Graph, 'Time intervals')
            ylabel(app.Graph, 'Dynamics')
            zlabel(app.Graph, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabResultWork

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end