classdef Task1_interf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        LastName         matlab.ui.control.EditField
        FirstName        matlab.ui.control.EditField
        Patronymic       matlab.ui.control.EditField
        ThirdLesson      matlab.ui.control.EditField
        FirstLesson      matlab.ui.control.EditField
        SecondLesson     matlab.ui.control.EditField
        PointsFirst      matlab.ui.control.NumericEditField
        PointsSecond     matlab.ui.control.NumericEditField
        PointsThird      matlab.ui.control.NumericEditField
        Button           matlab.ui.control.StateButton
        Label            matlab.ui.control.Label
        PassingScore     matlab.ui.control.NumericEditField
        Congratulations  matlab.ui.control.EditField
        Label_2          matlab.ui.control.Label
        Label_3          matlab.ui.control.Label
        Label_4          matlab.ui.control.Label
        Label_5          matlab.ui.control.Label
        Sum              matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
%             FirstLesson = app.FirstLessonEditField.Value;
%             SecondLesson = app.SecondLessonEditField.Value;
%             ThirdLesson = app.ThirdLessonEditField.Value;
            pointsFirst = app.PointsFirst.Value;
            pointsSecond = app.PointsSecond.Value;
            pointsThird = app.PointsThird.Value;
            if pointsFirst<=100 && pointsSecond<=100 && pointsThird<=100 && pointsFirst>=0 && pointsSecond>=0 && pointsThird>=0
                sum = pointsFirst+pointsSecond+pointsThird;
                app.Sum.Value = sum;
                passingScore = app.PassingScore.Value;
                if sum>=passingScore
                    congratulations = 'Поздравляем!!!';
                else
                    congratulations = 'Вы не поступили';
                end
                app.Congratulations.Value= congratulations;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LastName = uieditfield(app.UIFigure, 'text');
            app.FirstName = uieditfield(app.UIFigure, 'text');
            app.Patronymic = uieditfield(app.UIFigure, 'text');
            app.ThirdLesson = uieditfield(app.UIFigure, 'text');
            app.FirstLesson = uieditfield(app.UIFigure, 'text');
            app.SecondLesson = uieditfield(app.UIFigure, 'text');
            app.PointsFirst = uieditfield(app.UIFigure, 'numeric');
            app.PointsSecond = uieditfield(app.UIFigure, 'numeric');
            app.PointsThird = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Вычисление';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Проходной балл:';
            app.PassingScore = uieditfield(app.UIFigure, 'numeric');
            app.Congratulations = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Фамилия, имя, отчество';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Предмет';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Баллы ЕГЭ';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Сумма баллов';
            app.Sum = uieditfield(app.UIFigure, 'numeric');
            app.Sum.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Task1_interf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end